classdef H5CellProfilerConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileProcessingPanel           matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        LoadHDFFileEditFieldLabel     matlab.ui.control.Label
        EditField_filepath            matlab.ui.control.EditField
        OutputFileLabel               matlab.ui.control.Label
        EditField_exportpath          matlab.ui.control.EditField
        Button_filepath               matlab.ui.control.Button
        Button_exportpath             matlab.ui.control.Button
        Switch_OutputType             matlab.ui.control.Switch
        Text01                        matlab.ui.control.Label
        Text02                        matlab.ui.control.Label
        command                       matlab.ui.control.TextArea
        StructIndexingPanel           matlab.ui.container.Panel
        DropDown1                     matlab.ui.control.DropDown
        DropDown2                     matlab.ui.control.DropDown
        DropDown3                     matlab.ui.control.DropDown
        DropDown4                     matlab.ui.control.DropDown
        DropDown5                     matlab.ui.control.DropDown
        CheckBox1                     matlab.ui.control.CheckBox
        CheckBox2                     matlab.ui.control.CheckBox
        CheckBox3                     matlab.ui.control.CheckBox
        CheckBox4                     matlab.ui.control.CheckBox
        CheckBox5                     matlab.ui.control.CheckBox
        text03                        matlab.ui.control.Label
        text04                        matlab.ui.control.Label
        I1Label                       matlab.ui.control.Label
        I2Label                       matlab.ui.control.Label
        I3Label                       matlab.ui.control.Label
        I4Label                       matlab.ui.control.Label
        I5Label                       matlab.ui.control.Label
        ObservevalueLabel             matlab.ui.control.Label
        DropDown_SelectMetadata       matlab.ui.control.DropDown
        ListBox_SelectMetadata        matlab.ui.control.ListBox
        VariablePanel                 matlab.ui.container.Panel
        VariablenameEditFieldLabel    matlab.ui.control.Label
        EditField_Variablename        matlab.ui.control.EditField
        ObjectTypeDropDownLabel       matlab.ui.control.Label
        DropDown_ObjectType           matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel  matlab.ui.control.Label
        DropDown_MeasurementType      matlab.ui.control.DropDown
        OriginalVariableNameLabel     matlab.ui.control.Label
        ListBox_Inputname             matlab.ui.control.ListBox
        AssignedNameLabel             matlab.ui.control.Label
        ListBox_Datasetname           matlab.ui.control.ListBox
        Button_Add                    matlab.ui.control.Button
        Button_Remove                 matlab.ui.control.Button
        Button_MoveUp                 matlab.ui.control.Button
        Button_MoveDown               matlab.ui.control.Button
        Button_RESET                  matlab.ui.control.Button
        Button_LOAD                   matlab.ui.control.Button
        Button_SAVE                   matlab.ui.control.Button
        Button_RUN                    matlab.ui.control.Button
        Button_STOP                   matlab.ui.control.Button
        Text05                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        n = 0 % For record number of history command text area
        stop = 0 % For 'STOP' processing
        h5file % Contain input H5 pathname
        savefile % Contain destination MAT pathname
        Info % Description
        iminfo % Description
        basename % Description
        ObjectTypeList % Description
        MeasurementTypeList % Description
        metadataList % Description
        IdxsortedMeasurementTypeList % Description
        chosenMetadataInd % Description
        chosenIdxMeasure % Description
    end
    
    methods (Access = private)
        
        function chosenMetadataInd = updateUniquelist(app)
            chosenMetadata = {};
            chosenMetadataInd = [];
            
            if app.CheckBox1.Value
                chosenMetadataInd(length(chosenMetadataInd)+1) = 1;
                chosenMetadata{length(chosenMetadata)+1} = app.metadataList{app.DropDown1.Value};
                if app.CheckBox2.Value
                    chosenMetadataInd(length(chosenMetadataInd)+1) = 2;
                    chosenMetadata{length(chosenMetadata)+1} = app.metadataList{app.DropDown2.Value};
                    if app.CheckBox3.Value
                        chosenMetadataInd(length(chosenMetadataInd)+1) = 3;
                        chosenMetadata{length(chosenMetadata)+1} = app.metadataList{app.DropDown3.Value};
                        if app.CheckBox4.Value
                            chosenMetadataInd(length(chosenMetadataInd)+1) = 4;
                            chosenMetadata{length(chosenMetadata)+1} = app.metadataList{app.DropDown4.Value};
                            if app.CheckBox5.Value
                                chosenMetadataInd(length(chosenMetadataInd)+1) = 5;
                                chosenMetadata{length(chosenMetadata)+1} = app.metadataList{app.DropDown5.Value};
                            end
                        end
                    end
                end
            end
            if isempty(chosenMetadata)
                chosenMetadata = {'Chosen Metadata'};
            end
            
            app.DropDown_SelectMetadata.Items = chosenMetadata;
            
            if ~strcmp(chosenMetadata,'Chosen Metadata')
                ChosenMetadataName = chosenMetadata{1};
                ChosenMetadataFileName = [app.iminfo.Name '/' ChosenMetadataName '/data'];
                ChosenMetadata = h5read(app.h5file,ChosenMetadataFileName);
                ChosenMetadataUni = sort(unique(ChosenMetadata));
                app.ListBox_SelectMetadata.Items = ChosenMetadataUni;
            else
                app.ListBox_SelectMetadata.Items = {''};
            end
            
        end
        
        function [I1,I1Ind,I1Uni,I1UniInd,I1Name] = genMetaList(app,h5file,metadataList,Popupmenu_Value,imageName)
            I1Name = metadataList{Popupmenu_Value};
            I1DataName = [imageName '/' I1Name '/data'];
            I1IDName = [imageName '/' I1Name '/index'];
            I1 = h5read(h5file,I1DataName);
            if size(I1,1)==1
                I1 = I1';
            end
            I1Uni = unique(I1);
            I1UniInd=zeros(length(I1Uni),1);
            for i=1:length(I1Uni)
                temp = str2num(I1Uni{i});
                if isempty(temp)
                    I1UniInd(i) = i;
                else
                    I1UniInd(i) = temp;
                end
            end
            I1Uni = matlab.lang.makeValidName(I1Uni);
            I1Ind = h5read(h5file,I1IDName);
            if size(I1Ind,1)==3
                I1Ind = I1Ind';
            end
            I1Ind = sortrows(I1Ind,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % old UIFigure size (X,Y,W,H) : 100,100,1200,760
            
            % old command size (X,Y,W,H) : 560,120,600,570
            width = 600+(position(3)-1200);
            height = 570+(position(4)-760);
            app.command.Position = [ app.command.Position(1) app.command.Position(2) width height];
            
            % old Text05 size (X,Y,W,H) : 560,700,600,22
            X = 560*(position(3)/1200);
            Y = 700+(position(4)-760);
            app.Text05.Position = [ X Y app.Text05.Position(3) app.Text05.Position(4)];
            
            % old Text01 size (X,Y,W,H) : 30,717,498,30
            Y = 717+(position(4)-760);
            app.Text01.Position = [ app.Text01.Position(1) Y app.Text01.Position(3) app.Text01.Position(4)];
            
            % old Text02 size (X,Y,W,H) : 30,692,498,22
            Y = 692+(position(4)-760);
            app.Text02.Position = [ app.Text02.Position(1) Y  app.Text02.Position(3) app.Text02.Position(4)];
            
            % old FileProcessingPanel size (X,Y,W,H) : 30,590,498,100
            Y = 590*(position(4)/760);
            app.FileProcessingPanel.Position = [ app.FileProcessingPanel.Position(1) Y  app.FileProcessingPanel.Position(3) app.FileProcessingPanel.Position(4)];
            
            % old StructIndexingPanel size (X,Y,W,H) : 30,375,498,203
            Y = 375*(position(4)/760);
            app.StructIndexingPanel.Position = [ app.StructIndexingPanel.Position(1) Y  app.StructIndexingPanel.Position(3) app.StructIndexingPanel.Position(4)];
            
            % old VariablePanel size (X,Y,W,H) : 30,30,500,327
            Y = 30*(position(4)/760);
            app.VariablePanel.Position = [ app.VariablePanel.Position(1) Y  app.VariablePanel.Position(3) app.VariablePanel.Position(4)];
            
            % old Button_RESET size (X,Y,W,H) : 560,45,100,50
            X = 560*(position(3)/1200);
            app.Button_RESET.Position = [ X app.Button_RESET.Position(2) app.Button_RESET.Position(3) app.Button_RESET.Position(4)];
            
            % old Button_LOAD size (X,Y,W,H) : 685,45,100,50
            X = 685*(position(3)/1200);
            app.Button_LOAD.Position = [ X app.Button_LOAD.Position(2) app.Button_LOAD.Position(3) app.Button_LOAD.Position(4)];
            
            % old Button_SAVE size (X,Y,W,H) : 810,45,100,50
            X = 810*(position(3)/1200);
            app.Button_SAVE.Position = [ X app.Button_SAVE.Position(2) app.Button_SAVE.Position(3) app.Button_SAVE.Position(4)];
            
            % old Button_RUN size (X,Y,W,H) : 935,45,100,50
            X = 935*(position(3)/1200);
            app.Button_RUN.Position = [ X app.Button_RUN.Position(2) app.Button_RUN.Position(3) app.Button_RUN.Position(4)];
            
            % old Button_STOP size (X,Y,W,H) : 1058,45,100,50
            X = 1058*(position(3)/1200);
            app.Button_STOP.Position = [ X app.Button_STOP.Position(2) app.Button_STOP.Position(3) app.Button_STOP.Position(4)];
            
        end

        % Button pushed function: Button_filepath
        function Button_filepathPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            [FileName,PathName,FilterIndex] = uigetfile('*.h5','choose H5 file');
            if FilterIndex
                app.n = app.n + 1;
                app.EditField_filepath.Value = fullfile(PathName,FileName);
                app.command.Value{app.n} = sprintf('>> Loading File " %s " ...',FileName); pause(0.0001);
                
                app.h5file = fullfile(PathName,FileName);
                app.Info = h5info(app.h5file,'/Measurements');
                app.basename = app.Info.Groups.Name;
                
                % populate objectTypeList
                for j =1:length(app.Info.Groups(1).Groups)
                    temp =  app.Info.Groups(1).Groups(j).Name;
                    t2 = eval(['regexp('''  temp ''',''' '(?<=' app.basename '/).+''' ',''match'')']);
                    app.ObjectTypeList{j} = t2{1};
                end
                app.DropDown_ObjectType.Items = app.ObjectTypeList;
                app.DropDown_ObjectType.ItemsData = 1:length(app.ObjectTypeList);
                
                % identify index of the first Filename variable in Image
                eval(['app.iminfo=h5info(''' app.h5file ''',''' app.basename '/Image'   ''');'])
                
                i=1;
                for j=1:length(app.iminfo.Groups)
                    temp =  app.iminfo.Groups(j).Name;
                    t3 = eval(['regexp('''  temp ''',''' '(?<=' app.iminfo.Name '/).+''' ',''match'')']);
                    out = strfind(t3{1},'Metadata_');
                    if ~isempty(out)
                        app.metadataList{i,1} = t3{1};
                        i=i+1;
                    end
                end
                app.metadataList = sort(app.metadataList);
                
                app.DropDown1.Items = app.metadataList;
                app.DropDown1.ItemsData = 1:length(app.metadataList);
                app.DropDown1.Value = 1;
                app.DropDown2.Items = app.metadataList;
                app.DropDown2.ItemsData = 1:length(app.metadataList);
                app.DropDown2.Value = 1;
                app.DropDown3.Items = app.metadataList;
                app.DropDown3.ItemsData = 1:length(app.metadataList);
                app.DropDown3.Value = 1;
                app.DropDown4.Items = app.metadataList;
                app.DropDown4.ItemsData = 1:length(app.metadataList);
                app.DropDown4.Value = 1;
                app.DropDown5.Items = app.metadataList;
                app.DropDown5.ItemsData = 1:length(app.metadataList);
                app.DropDown5.Value = 1;
                
                % populate MeasurementTypeList
                for j =1:length(app.Info.Groups.Groups(1).Groups)
                    temp =  app.Info.Groups.Groups(1).Groups(j).Name;
                    t2 = eval(['regexp('''  temp ''',''' '(?<=' app.Info.Groups.Groups(1).Name '/).+''' ',''match'')']);
                    app.MeasurementTypeList{j} = t2{1};
                end
                [app.MeasurementTypeList,app.IdxsortedMeasurementTypeList] = sort(app.MeasurementTypeList);
                app.DropDown_MeasurementType.Items = app.MeasurementTypeList;
                app.DropDown_MeasurementType.ItemsData = 1:length(app.MeasurementTypeList);
                app.DropDown_MeasurementType.Value = 1;
                
                app.command.Value{app.n} = sprintf('%s Completed.',app.command.Value{app.n}); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_exportpath
        function Button_exportpathPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            if strcmp(app.Switch_OutputType.Value,'MAT')
                [FileName,PathName,FilterIndex] = uiputfile('*.mat','Save Created Dataset As');
            elseif strcmp(app.Switch_OutputType.Value,'CSV')
                [FileName,PathName,FilterIndex] = uiputfile('*.csv','Save Created Dataset As');
            end
            if FilterIndex
                app.n = app.n + 1;
                app.savefile = fullfile(PathName,FileName);
                app.EditField_exportpath.Value = fullfile(PathName,FileName);
                app.command.Value{app.n} = sprintf('>> Created Save File as " %s " ... Completed.',FileName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: EditField_filepath
        function EditField_filepathValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            
            value = app.EditField_filepath.Value;
            [~,FileName,ext] = fileparts(value);
            FilterIndex = contains(ext,'.h5');
            
            app.n = app.n + 1;
            
            if FilterIndex
                
                app.command.Value{app.n} = sprintf('>> Loading File " %s " ...',[FileName,ext]); pause(0.0001);
                
                app.h5file = value;
                app.Info = h5info(app.h5file,'/Measurements');
                app.basename = app.Info.Groups.Name;
                
                % populate objectTypeList
                for j =1:length(app.Info.Groups(1).Groups)
                    temp =  app.Info.Groups(1).Groups(j).Name;
                    t2 = eval(['regexp('''  temp ''',''' '(?<=' app.basename '/).+''' ',''match'')']);
                    app.ObjectTypeList{j} = t2{1};
                end
                app.DropDown_ObjectType.Items = app.ObjectTypeList;
                app.DropDown_ObjectType.ItemsData = 1:length(app.ObjectTypeList);
                
                % identify index of the first Filename variable in Image
                eval(['app.iminfo=h5info(''' app.h5file ''',''' app.basename '/Image'   ''');'])
                
                i=1;
                for j=1:length(app.iminfo.Groups)
                    temp =  app.iminfo.Groups(j).Name;
                    t3 = eval(['regexp('''  temp ''',''' '(?<=' app.iminfo.Name '/).+''' ',''match'')']);
                    out = strfind(t3{1},'Metadata_');
                    if ~isempty(out)
                        app.metadataList{i,1} = t3{1};
                        i=i+1;
                    end
                end
                app.metadataList = sort(app.metadataList);
                
                app.DropDown1.Items = app.metadataList;
                app.DropDown1.ItemsData = 1:length(app.metadataList);
                app.DropDown1.Value = 1;
                app.DropDown2.Items = app.metadataList;
                app.DropDown2.ItemsData = 1:length(app.metadataList);
                app.DropDown2.Value = 1;
                app.DropDown3.Items = app.metadataList;
                app.DropDown3.ItemsData = 1:length(app.metadataList);
                app.DropDown3.Value = 1;
                app.DropDown4.Items = app.metadataList;
                app.DropDown4.ItemsData = 1:length(app.metadataList);
                app.DropDown4.Value = 1;
                app.DropDown5.Items = app.metadataList;
                app.DropDown5.ItemsData = 1:length(app.metadataList);
                app.DropDown5.Value = 1;
                
                % populate MeasurementTypeList
                for j =1:length(app.Info.Groups.Groups(1).Groups)
                    temp =  app.Info.Groups.Groups(1).Groups(j).Name;
                    t2 = eval(['regexp('''  temp ''',''' '(?<=' app.Info.Groups.Groups(1).Name '/).+''' ',''match'')']);
                    app.MeasurementTypeList{j} = t2{1};
                end
                app.MeasurementTypeList = sort(app.MeasurementTypeList);
                app.DropDown_MeasurementType.Items = app.MeasurementTypeList;
                app.DropDown_MeasurementType.ItemsData = 1:length(app.MeasurementTypeList);
                app.DropDown_MeasurementType.Value = 1;
                
                app.command.Value{app.n} = sprintf('%s Completed.',app.command.Value{app.n}); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            else
                app.command.Value{app.n} = sprintf('>> Error! Please check the "Load HDF File" path.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
            end
        end

        % Value changed function: EditField_exportpath
        function EditField_exportpathValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.EditField_exportpath.Value;
            [~,FileName,ext] = fileparts(value);
            
            if strcmp(app.Switch_OutputType.Value,'MAT')
                FilterIndex = contains(ext,'.mat');
            elseif strcmp(app.Switch_OutputType.Value,'CSV')
                FilterIndex = contains(ext,'.csv');
            end
            app.n = app.n + 1;
            if FilterIndex
                
                app.savefile = value;
                app.command.Value{app.n} = sprintf('>> Created Save File as " %s " ... Completed.',[FileName,ext]); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            else
                app.command.Value{app.n} = sprintf('>> Error! Please check the "Output File" path.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
            end
        end

        % Value changed function: CheckBox1
        function CheckBox1ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            if isempty(app.h5file)
                app.CheckBox1.Value = 0;
                app.command.Value{app.n} = sprintf('>> Cannot select any Metadata. Please load H5 file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            value = app.CheckBox1.Value;
            if value
                MetadataName = app.metadataList{app.DropDown1.Value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I1.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            else
                app.DropDown1.Value = 1;
                app.command.Value{app.n} = sprintf('>> De-selected index in I1.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.chosenMetadataInd.Items = updateUniquelist(app);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            if isempty(app.h5file)
                app.CheckBox2.Value = 0;
                app.command.Value{app.n} = sprintf('>> Cannot select any Metadata. Please load H5 file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            value = app.CheckBox2.Value;
            if value
                MetadataName = app.metadataList{app.DropDown2.Value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I2.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            else
                app.DropDown2.Value = 1;
                app.command.Value{app.n} = sprintf('>> De-selected index in I2.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.chosenMetadataInd.Items = updateUniquelist(app);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: CheckBox3
        function CheckBox3ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            if isempty(app.h5file)
                app.CheckBox3.Value = 0;
                app.command.Value{app.n} = sprintf('>> Cannot select any Metadata. Please load H5 file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            value = app.CheckBox3.Value;
            if value
                MetadataName = app.metadataList{app.DropDown3.Value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I3.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            else
                app.DropDown3.Value = 1;
                app.command.Value{app.n} = sprintf('>> De-selected index in I3.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.chosenMetadataInd.Items = updateUniquelist(app);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: CheckBox4
        function CheckBox4ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            if isempty(app.h5file)
                app.CheckBox4.Value = 0;
                app.command.Value{app.n} = sprintf('>> Cannot select any Metadata. Please load H5 file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            value = app.CheckBox4.Value;
            if value
                MetadataName = app.metadataList{app.DropDown4.Value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I4.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            else
                app.DropDown4.Value = 1;
                app.command.Value{app.n} = sprintf('>> De-selected index in I4.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.chosenMetadataInd.Items = updateUniquelist(app);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: CheckBox5
        function CheckBox5ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            if isempty(app.h5file)
                app.CheckBox5.Value = 0;
                app.command.Value{app.n} = sprintf('>> Cannot select any Metadata. Please load H5 file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            value = app.CheckBox5.Value;
            if value
                MetadataName = app.metadataList{app.DropDown5.Value};
                app.chosenMetadataInd = updateUniquelist(app);
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I5.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            else
                app.DropDown5.Value = 1;
                app.command.Value{app.n} = sprintf('>> De-selected index in I5.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.chosenMetadataInd.Items = updateUniquelist(app);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown1
        function DropDown1ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.DropDown1.Value;
            if app.CheckBox1.Value
                app.n = app.n + 1;
                MetadataName = app.metadataList{value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I1.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.DropDown2.Value;
            if app.CheckBox2.Value
                app.n = app.n + 1;
                MetadataName = app.metadataList{value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I2.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown3
        function DropDown3ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.DropDown3.Value;
            if app.CheckBox3.Value
                app.n = app.n + 1;
                MetadataName = app.metadataList{value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I3.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown4
        function DropDown4ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.DropDown4.Value;
            if app.CheckBox4.Value
                app.n = app.n + 1;
                MetadataName = app.metadataList{value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I4.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown5
        function DropDown5ValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1;
            value = app.DropDown5.Value;
            if app.CheckBox5.Value
                app.n = app.n + 1;
                MetadataName = app.metadataList{value};
                app.command.Value{app.n} = sprintf('>> Selected " %s " as a index in I5.',MetadataName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown_SelectMetadata
        function DropDown_SelectMetadataValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001);
            value = app.DropDown_SelectMetadata.Value;
            if ~strcmp(value,'Chosen Metadata')
                ChosenMetadataName = value;
                ChosenMetadataFileName = [app.iminfo.Name '/' ChosenMetadataName '/data'];
                ChosenMetadata = h5read(app.h5file,ChosenMetadataFileName);
                ChosenMetadataUni = sort(unique(ChosenMetadata));
                app.ListBox_SelectMetadata.Items = ChosenMetadataUni;
            else
                app.ListBox_SelectMetadata.Items = {''};
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: DropDown_ObjectType
        function DropDown_ObjectTypeValueChanged(app, event)
            value = app.DropDown_ObjectType.Value;
            for j =1:length(app.Info.Groups.Groups(value).Groups)
                temp =  app.Info.Groups.Groups(value).Groups(j).Name;
                t2 = eval(['regexp('''  temp ''',''' '(?<=' app.Info.Groups.Groups(value).Name '/).+''' ',''match'')']);
                app.MeasurementTypeList{j} = t2{1};
            end
            [sortedMeasurementTypeList,app.IdxsortedMeasurementTypeList] = sort(app.MeasurementTypeList);
            app.DropDown_MeasurementType.Items = sortedMeasurementTypeList;
            app.DropDown_MeasurementType.ItemsData = 1:length(sortedMeasurementTypeList);
            app.DropDown_MeasurementType.Value = 1;
            
            app.chosenIdxMeasure = 1;
        end

        % Value changed function: DropDown_MeasurementType
        function DropDown_MeasurementTypeValueChanged(app, event)
            value = app.DropDown_MeasurementType.Value;
            app.chosenIdxMeasure = value;
        end

        % Value changed function: ListBox_Inputname
        function ListBox_InputnameValueChanged(app, event)
            value = app.ListBox_Inputname.Value;
            app.ListBox_Datasetname.Value = value;
        end

        % Value changed function: ListBox_Datasetname
        function ListBox_DatasetnameValueChanged(app, event)
            value = app.ListBox_Datasetname.Value;
            app.ListBox_Inputname.Value = value;
        end

        % Button pushed function: Button_Add
        function Button_AddPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            
            Oval = app.DropDown_ObjectType.Value;
            Mval = app.DropDown_MeasurementType.Value;
            
            currentInputList = app.ListBox_Inputname.Items;
            currentdatasetList = app.ListBox_Datasetname.Items;
            
            if isempty(app.ObjectTypeList) && isempty(app.MeasurementTypeList)
                app.command.Value{app.n} = sprintf('>> Error! Cannot Add Variable. Please check HDF file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            
            variableName = [app.ObjectTypeList{Oval} '/' app.MeasurementTypeList{Mval}];
            
            %Check whether input variable name is blank space
            assignName = app.EditField_Variablename.Value;
            
            if isempty(assignName)
                assignName = app.MeasurementTypeList{Mval};
            end
            
            %Specify input list and dataset list
            if isempty(currentInputList)
                currentInputList{1} = variableName;
                currentdatasetList{1} = assignName;
            else
                currentInputList{length(currentInputList)+1} = variableName;
                currentdatasetList{length(currentdatasetList)+1} = assignName;
            end
            
            app.ListBox_Inputname.Items = currentInputList;
            app.ListBox_Inputname.ItemsData = 1:length(currentInputList);
            app.ListBox_Datasetname.Items = currentdatasetList;
            app.ListBox_Datasetname.ItemsData = 1:length(currentdatasetList);
            
            app.ListBox_Inputname.Value = length(currentInputList);
            app.ListBox_Datasetname.Value = length(currentdatasetList);
            
            app.command.Value{app.n} = sprintf('>> Add Variable : " %s " as " %s ".',variableName,assignName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_Remove
        function Button_RemovePushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            
            Inval = app.ListBox_Inputname.Value;
            
            currentInputList = app.ListBox_Inputname.Items;
            currentdatasetList = app.ListBox_Datasetname.Items;
            
            if isempty(app.ObjectTypeList) && isempty(app.MeasurementTypeList)
                app.command.Value{app.n} = sprintf('>> Error! Variable is not found. Please check HDF file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            
            assignName = app.ListBox_Datasetname.Items{Inval};
            
            if length(currentInputList)>1
                j=1;
                oldList = [1:Inval-1 Inval+1:length(currentInputList)];
                for i=oldList
                    NewInputList{j} = currentInputList{i};
                    NewdatasetList{j} = currentdatasetList{i};
                    j=j+1;
                end
                app.ListBox_Inputname.Items = NewInputList;
                app.ListBox_Inputname.ItemsData = 1:length(NewInputList);
                app.ListBox_Datasetname.Items = NewdatasetList;
                app.ListBox_Datasetname.ItemsData = 1:length(NewdatasetList);
                
                if Inval>1
                    app.ListBox_Inputname.Value = Inval-1;
                    app.ListBox_Datasetname.Value = Inval-1;
                else
                    app.ListBox_Inputname.Value = 1;
                    app.ListBox_Datasetname.Value = 1;
                end
            else
                app.ListBox_Inputname.Items = {};
                app.ListBox_Inputname.ItemsData = [];
                app.ListBox_Datasetname.Items = {};
                app.ListBox_Datasetname.ItemsData = [];
            end
            
            app.command.Value{app.n} = sprintf('>> Remove Variable : " %s " successfully.',assignName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_MoveUp
        function Button_MoveUpPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            currentInputList = app.ListBox_Inputname.Items;
            Dataval = app.ListBox_Datasetname.Value;
            currentdatasetList = app.ListBox_Datasetname.Items;
            
            if isempty(app.ObjectTypeList) && isempty(app.MeasurementTypeList)
                app.command.Value{app.n} = sprintf('>> Error! Variable is not found. Please check HDF file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            
            assignName = app.ListBox_Datasetname.Items{Dataval};
            
            if Dataval > 1
                AboveString = currentdatasetList{Dataval-1};
                CurrentString = currentdatasetList{Dataval};
                currentdatasetList{Dataval} = AboveString;
                currentdatasetList{Dataval-1} = CurrentString;
                
                AboveInputString = currentInputList{Dataval-1};
                CurrentInputString = currentInputList{Dataval};
                currentInputList{Dataval} = AboveInputString;
                currentInputList{Dataval-1} = CurrentInputString;
                
                app.ListBox_Datasetname.Items = currentdatasetList;
                app.ListBox_Inputname.Items = currentInputList;
                app.ListBox_Datasetname.Value = Dataval-1;
                app.ListBox_Inputname.Value = Dataval-1;
            end
            
            app.command.Value{app.n} = sprintf('>> Move up Variable : " %s " successfully.',assignName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_MoveDown
        function Button_MoveDownPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            currentInputList = app.ListBox_Inputname.Items;
            Dataval = app.ListBox_Datasetname.Value;
            currentdatasetList = app.ListBox_Datasetname.Items;
            
            if isempty(app.ObjectTypeList) && isempty(app.MeasurementTypeList)
                app.command.Value{app.n} = sprintf('>> Error! Variable is not found. Please check HDF file.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                app.Lamp.Color = 'r';
                return;
            end
            
            assignName = app.ListBox_Datasetname.Items{Dataval};
            
            if Dataval < length(currentdatasetList)
                BelowString = currentdatasetList{Dataval+1};
                CurrentString = currentdatasetList{Dataval};
                currentdatasetList{Dataval} = BelowString;
                currentdatasetList{Dataval+1} = CurrentString;
                
                BelowInputString = currentInputList{Dataval+1};
                CurrentInputString = currentInputList{Dataval};
                currentInputList{Dataval} = BelowInputString;
                currentInputList{Dataval+1} = CurrentInputString;
                
                app.ListBox_Datasetname.Items = currentdatasetList;
                app.ListBox_Inputname.Items = currentInputList;
                app.ListBox_Datasetname.Value = Dataval+1;
                app.ListBox_Inputname.Value = Dataval+1;
            end
            
            app.command.Value{app.n} = sprintf('>> Move down Variable : " %s " successfully.',assignName); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_RESET
        function Button_RESETPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            
            app.EditField_filepath.Value = '';
            app.EditField_exportpath.Value = '';
            
            app.DropDown1.Items = {'metadataI1'};
            app.DropDown2.Items = {'metadataI2'};
            app.DropDown3.Items = {'metadataI3'};
            app.DropDown4.Items = {'metadataI4'};
            app.DropDown5.Items = {'metadataI5'};
            
            app.CheckBox1.Value = 0;
            app.CheckBox2.Value = 0;
            app.CheckBox3.Value = 0;
            app.CheckBox4.Value = 0;
            app.CheckBox5.Value = 0;
            
            app.DropDown_SelectMetadata.Items = {'Chosen Metadata'};
            app.ListBox_SelectMetadata.Items = {};
            
            app.EditField_Variablename.Value = '';
            app.DropDown_ObjectType.Items = {'Selected Object'};
            app.DropDown_MeasurementType.Items = {'Selected Object'};
            app.ListBox_Inputname.Items = {};
            app.ListBox_Datasetname.Items = {};
            
            app.stop = 0;
            app.h5file = [];
            app.Info = [];
            app.iminfo = [];
            app.basename = [];
            app.ObjectTypeList = [];
            app.MeasurementTypeList = [];
            app.metadataList = [];
            app.IdxsortedMeasurementTypeList = [];
            app.chosenMetadataInd = [];
            app.chosenIdxMeasure = [];
            
            app.command.Value{app.n} = sprintf('>> Clear all Variable successfully.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_LOAD
        function Button_LOADPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            
            [FileName,PathName,FilterIndex] = uigetfile('*.mat','Select MAT-file of the input and dataset lists');
            if FilterIndex
                load(fullfile(PathName,FileName));
                
                app.ListBox_Inputname.Items = currentInputList;
                app.ListBox_Inputname.ItemsData = 1:length(currentInputList);
                app.ListBox_Datasetname.Items = currentdatasetList;
                app.ListBox_Datasetname.ItemsData = 1:length(currentdatasetList);
            end
            
            
            app.command.Value{app.n} = sprintf('>> Load all Variable successfully.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_SAVE
        function Button_SAVEPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            
            currentInputList = app.ListBox_Inputname.Items;
            currentdatasetList = app.ListBox_Datasetname.Items;
            
            [file,path,FilterIndex] = uiputfile('*.mat','Save Input and Dataset Lists As',pwd);
            if FilterIndex
                saveparamfile = fullfile(path,file);
                save(saveparamfile,'currentInputList','currentdatasetList');
            end
            
            app.command.Value{app.n} = sprintf('>> Save all Variable successfully.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_RUN
        function Button_RUNPushed(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            app.stop = 0; flag = 0;
            
            app.command.Value{app.n} = sprintf('>> Run collecting data ...'); pause(0.0001);
            
            mydataMatrix = [];
            
            inputList = app.ListBox_Inputname.Items;
            fName = app.ListBox_Datasetname.Items;
            
            % process whether there is any chosen metadata
            I1val = app.CheckBox1.Value;
            I2val = app.CheckBox2.Value;
            I3val = app.CheckBox3.Value;
            I4val = app.CheckBox4.Value;
            I5val = app.CheckBox5.Value;
            
            I1 = [];
            I2 = [];
            I3 = [];
            I4 = [];
            I5 = [];
            
            I1Ind = [];
            I2Ind = [];
            I3Ind = [];
            I4Ind = [];
            I5Ind = [];
            
            if I1val
                [I1,I1Ind,~,~,I1Name] = genMetaList(app,app.h5file,app.metadataList,app.DropDown1.Value,app.iminfo.Name);
                if I2val
                    [I2,I2Ind,~,~,I2Name] = genMetaList(app,app.h5file,app.metadataList,app.DropDown2.Value,app.iminfo.Name);
                    if I3val
                        [I3,I3Ind,~,~,I3Name] = genMetaList(app,app.h5file,app.metadataList,app.DropDown3.Value,app.iminfo.Name);
                        if I4val
                            [I4,I4Ind,~,~,I4Name] = genMetaList(app,app.h5file,app.metadataList,app.DropDown4.Value,app.iminfo.Name);
                            if I5val
                                [I5,I5Ind,~,~,I5Name] = genMetaList(app,app.h5file,app.metadataList,app.DropDown5.Value,app.iminfo.Name);
                            end
                        end
                    end
                end
            end
            
            if strcmp(app.Switch_OutputType.Value,'MAT')
                
                for k=1:length(inputList)
                    app.n = app.n + 1;
                    for imIndex = 1:length(I1)
                        
                        app.command.Value{app.n} = sprintf('Generating %d of %d : %3.0f %% ...',k,length(inputList),100*imIndex/length(I1)); pause(0.0001);
                        if app.stop
                            flag = 1;
                            break;
                        end
                        
                        saveName = matlab.lang.makeValidName(fName{k});
                        
                        if I1val
                            currentI1 = str2double(I1{imIndex});
                        end
                        if I2val
                            currentI2 = str2double(I2{imIndex});
                        end
                        if I3val
                            currentI3 = str2double(I3{imIndex});
                        end
                        if I4val
                            currentI4 = str2double(I4{imIndex});
                        end
                        if I5val
                            currentI5 = str2double(I5{imIndex});
                        end
                        
                        if I1val
                            if I2val
                                if I3val
                                    if I4val
                                        if I5val
                                            mydataMatrix{currentI1,currentI2,currentI3,currentI4,currentI5}.(saveName) = [];
                                        else
                                            mydataMatrix{currentI1,currentI2,currentI3,currentI4}.(saveName) = [];
                                        end
                                    else
                                        mydataMatrix{currentI1,currentI2,currentI3}.(saveName) = [];
                                    end
                                else
                                    mydataMatrix{currentI1,currentI2}.(saveName) = [];
                                end
                            else
                                mydataMatrix{currentI1}.(saveName) = [];
                            end
                        end
                        
                        clear objectMeasurementName saveName currentList currentI1 currentI2 currentI3 currentI4;
                        
                    end
                    if flag
                        break;
                    end
                    app.command.Value{app.n} = sprintf('Generating %d of %d : %3.0f %% ... Completed.',k,length(inputList),100*imIndex/length(I1)); pause(0.0001);
                end
                
                if flag
                    app.stop = 0; % rest the button state
                    app.n = app.n + 1;
                    app.command.Value{app.n} = sprintf('>> Stop Processing!'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                    app.Lamp.Color = [0.9 0.9 0.9];
                    return;
                end
                
                for k = 1:length(inputList)
                    app.n = app.n + 1;
                    
                    saveName = matlab.lang.makeValidName(fName{k});
                    objectMeasurementName = [app.basename '/' inputList{k} '/data'];
                    currentInd = h5read(app.h5file,[app.basename '/' inputList{k} '/index'])';
                    if size(currentInd,1)==3
                        currentInd = currentInd';
                    end
                    currentInd = sortrows(currentInd,1);
                    for i = 1:length(currentInd)
                        
                        app.command.Value{app.n} = sprintf('Processing %d of %d : %3.0f %% ...',k,length(inputList),100*i/length(currentInd)); pause(0.0001);
                        if app.stop
                            flag = 1;
                            break;
                        end
                        
                        imIndex = currentInd(i,1);
                        RStart = double(currentInd(i,2)+1);
                        Rlength = double(currentInd(i,3)-currentInd(i,2));
                        if Rlength == 0
                            continue;
                        end
                        
                        
                        if I1val
                            currentI1 = str2double(I1{I1Ind(imIndex,3)});
                        end
                        if I2val
                            currentI2 = str2double(I2{I2Ind(imIndex,3)});
                        end
                        if I3val
                            currentI3 = str2double(I3{I3Ind(imIndex,3)});
                        end
                        if I4val
                            currentI4 = str2double(I4{I4Ind(imIndex,3)});
                        end
                        if I5val
                            currentI5 = str2double(I5{I5Ind(imIndex,3)});
                        end
                        
                        if I1val
                            if I2val
                                if I3val
                                    if I4val
                                        if I5val
                                            mydataMatrix{currentI1,currentI2,currentI3,currentI4,currentI5}.(saveName) = ...
                                                [mydataMatrix{currentI1,currentI2,currentI3,currentI4,currentI5}.(saveName) ; h5read(app.h5file,objectMeasurementName,RStart,Rlength)];
                                        else
                                            mydataMatrix{currentI1,currentI2,currentI3,currentI4}.(saveName) = ...
                                                [mydataMatrix{currentI1,currentI2,currentI3,currentI4}.(saveName) ; h5read(app.h5file,objectMeasurementName,RStart,Rlength)];
                                        end
                                    else
                                        mydataMatrix{currentI1,currentI2,currentI3}.(saveName) = ...
                                            [mydataMatrix{currentI1,currentI2,currentI3}.(saveName) ; h5read(app.h5file,objectMeasurementName,RStart,Rlength)];
                                    end
                                else
                                    mydataMatrix{currentI1,currentI2}.(saveName) = ...
                                        [mydataMatrix{currentI1,currentI2}.(saveName) ; h5read(app.h5file,objectMeasurementName,RStart,Rlength)];
                                end
                            else
                                mydataMatrix{currentI1}.(saveName) = ...
                                    [mydataMatrix{currentI1}.(saveName) ; h5read(app.h5file,objectMeasurementName,RStart,Rlength)];
                            end
                        end
                        
                        clear currentList currentI1 currentI2 currentI3 currentI4 currentI5;
                        
                    end
                    if flag
                        break;
                    end
                    app.command.Value{app.n} = sprintf('Processing %d of %d : %3.0f %% ... Completed.',k,length(inputList),100*i/length(currentInd)); pause(0.0001);
                end
                
                if flag
                    app.stop = 0; % rest the button state
                    app.n = app.n + 1;
                    app.command.Value{app.n} = sprintf('>> Stop Processing!'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                    app.Lamp.Color = [0.9 0.9 0.9];
                    return;
                end
                
                save(app.savefile,'mydataMatrix','-v7.3');
                
            elseif strcmp(app.Switch_OutputType.Value,'CSV')
                
                app.n = app.n + 1;
                
                currentInd = h5read(app.h5file,[app.basename '/' inputList{1} '/index'])';
                if size(currentInd,1)==3
                    currentInd = currentInd';
                end
                currentInd = sortrows(currentInd,1);
                
                dat = []; saveName = cell(1,1); kn = 1;
                
                for i = 1:length(currentInd)
                    
                    app.command.Value{app.n} = sprintf('Processing table : %3.0f %% ...',100*i/length(currentInd)); pause(0.0001);
                    if app.stop
                        flag = 1;
                        break;
                    end
                    
                    imIndex = currentInd(i,1);
                    RStart = double(currentInd(i,2)+1);
                    Rlength = double(currentInd(i,3)-currentInd(i,2));
                    if Rlength == 0
                        continue;
                    end
                    
                    indx = kn:Rlength+kn-1;
                    kn = kn + Rlength;
                    
                    if I1val
                        if I2val
                            if I3val
                                if I4val
                                    if I5val
                                        saveName(1,1:6) = { I1Name, I2Name, I3Name, I4Name, I5Name, 'ObjectNumber' };
                                        dat(indx,1:6) = [ str2double(I1{I1Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I2{I2Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I3{I3Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I4{I4Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I5{I5Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            (1:Rlength)' ];
                                        jlast = 6;
                                    else
                                        saveName(1,1:5) = { I1Name, I2Name, I3Name, I4Name, 'ObjectNumber' };
                                        dat(indx,1:5) = [ str2double(I1{I1Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I2{I2Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I3{I3Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            str2double(I4{I4Ind(imIndex,3)}).*ones(Rlength,1) ...
                                            (1:Rlength)' ];
                                        jlast = 5;
                                    end
                                else
                                    saveName(1,1:4) = { I1Name, I2Name, I3Name, 'ObjectNumber' };
                                    dat(indx,1:4) = [ str2double(I1{I1Ind(imIndex,3)}).*ones(Rlength,1) ...
                                        str2double(I2{I2Ind(imIndex,3)}).*ones(Rlength,1) ...
                                        str2double(I3{I3Ind(imIndex,3)}).*ones(Rlength,1) ...
                                        (1:Rlength)' ];
                                    jlast = 4;
                                end
                            else
                                saveName(1,1:3) = { I1Name, I2Name, 'ObjectNumber' };
                                dat(indx,1:3) = [ str2double(I1{I1Ind(imIndex,3)}).*ones(Rlength,1) ...
                                    str2double(I2{I2Ind(imIndex,3)}).*ones(Rlength,1) ...
                                    (1:Rlength)' ];
                                jlast = 3;
                            end
                        else
                            saveName(1,2) = { I1Name, 'ObjectNumber' };
                            dat(indx,2) = [ str2double(I1{I1Ind(imIndex,3)}).*ones(Rlength,1) ...
                                (1:Rlength)' ];
                            jlast = 2;
                        end
                    end
                    
                    for j = 1:length(inputList)
                        if i == 1
                            saveName{1,j+jlast} = matlab.lang.makeValidName(fName{j});
                        end
                        objectMeasurementName = [app.basename '/' inputList{j} '/data'];
                        dat(indx,j+jlast) = h5read(app.h5file,objectMeasurementName,RStart,Rlength);
                    end
                    
                end
                if flag
                    app.stop = 0; % rest the button state
                    app.n = app.n + 1;
                    app.command.Value{app.n} = sprintf('>> Stop Processing!'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
                    app.Lamp.Color = [0.9 0.9 0.9];
                    return;
                end
                
                app.command.Value{app.n} = sprintf('Processing table : %3.0f %% ... Completed.',100*i/length(currentInd)); pause(0.0001);
                
                T = array2table(dat,'VariableNames',saveName);
                writetable(T,app.savefile);
            end
            app.n = app.n + 1;
            app.command.Value{app.n} = sprintf('>> Done storing data.'); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_STOP
        function Button_STOPPushed(app, event)
            app.stop = 1; %trigger the stop button state
        end

        % Value changed function: Switch_OutputType
        function Switch_OutputTypeValueChanged(app, event)
            app.Lamp.Color = 'y'; pause(0.0001); app.n = numel(app.command.Value)-1; app.n = app.n + 1;
            value = app.Switch_OutputType.Value;
            app.EditField_exportpath.Value = '';
            app.command.Value{app.n} = sprintf('>> Switch output to ".%s". Please select new "Output File".',value); app.command.Value{app.n+1} = '>>'; pause(0.0001);
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.FileProcessingPanel = uipanel(app.UIFigure);
            app.FileProcessingPanel.Title = 'File Processing';
            app.Lamp = uilamp(app.FileProcessingPanel);
            app.LoadHDFFileEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.LoadHDFFileEditFieldLabel.Text = 'Load HDF File';
            app.EditField_filepath = uieditfield(app.FileProcessingPanel, 'text');
            app.EditField_filepath.ValueChangedFcn = createCallbackFcn(app, @EditField_filepathValueChanged, true);
            app.OutputFileLabel = uilabel(app.FileProcessingPanel);
            app.OutputFileLabel.Text = 'Output File';
            app.EditField_exportpath = uieditfield(app.FileProcessingPanel, 'text');
            app.EditField_exportpath.ValueChangedFcn = createCallbackFcn(app, @EditField_exportpathValueChanged, true);
            app.Button_filepath = uibutton(app.FileProcessingPanel, 'push');
            app.Button_filepath.ButtonPushedFcn = createCallbackFcn(app, @Button_filepathPushed, true);
            app.Button_filepath.Icon = 'Folder-icon.png';
            app.Button_filepath.Text = '';
            app.Button_exportpath = uibutton(app.FileProcessingPanel, 'push');
            app.Button_exportpath.ButtonPushedFcn = createCallbackFcn(app, @Button_exportpathPushed, true);
            app.Button_exportpath.Icon = 'Folder-icon.png';
            app.Button_exportpath.Text = '';
            app.Switch_OutputType = uiswitch(app.FileProcessingPanel, 'slider');
            app.Switch_OutputType.Items = {'MAT', 'CSV'};
            app.Switch_OutputType.ValueChangedFcn = createCallbackFcn(app, @Switch_OutputTypeValueChanged, true);
            app.Switch_OutputType.Value = 'MAT';
            app.Text01 = uilabel(app.UIFigure);
            app.Text01.Text = 'HDF5 CellProfiler Converter';
            app.Text02 = uilabel(app.UIFigure);
            app.Text02.Text = {'Version. 18-02-2020'; '@ 2020 Kriengkrai Phongkitkarun. All rights reserved.'};
            app.command = uitextarea(app.UIFigure);
            app.command.Editable = 'off';
            app.command.Value = {'>>'};
            app.StructIndexingPanel = uipanel(app.UIFigure);
            app.StructIndexingPanel.Title = 'Struct Indexing';
            app.DropDown1 = uidropdown(app.StructIndexingPanel);
            app.DropDown1.Items = {'metadataI1'};
            app.DropDown1.ValueChangedFcn = createCallbackFcn(app, @DropDown1ValueChanged, true);
            app.DropDown1.Value = 'metadataI1';
            app.DropDown2 = uidropdown(app.StructIndexingPanel);
            app.DropDown2.Items = {'metadataI2'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'metadataI2';
            app.DropDown3 = uidropdown(app.StructIndexingPanel);
            app.DropDown3.Items = {'metadataI3'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = 'metadataI3';
            app.DropDown4 = uidropdown(app.StructIndexingPanel);
            app.DropDown4.Items = {'metadataI4'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @DropDown4ValueChanged, true);
            app.DropDown4.Value = 'metadataI4';
            app.DropDown5 = uidropdown(app.StructIndexingPanel);
            app.DropDown5.Items = {'metadataI5'};
            app.DropDown5.ValueChangedFcn = createCallbackFcn(app, @DropDown5ValueChanged, true);
            app.DropDown5.Value = 'metadataI5';
            app.CheckBox1 = uicheckbox(app.StructIndexingPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.CheckBox2 = uicheckbox(app.StructIndexingPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = '';
            app.CheckBox3 = uicheckbox(app.StructIndexingPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox3ValueChanged, true);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.StructIndexingPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox4ValueChanged, true);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.StructIndexingPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox5ValueChanged, true);
            app.CheckBox5.Text = '';
            app.text03 = uilabel(app.StructIndexingPanel);
            app.text03.Text = 'Select parameter for indexing ( save as                                                )';
            app.text04 = uilabel(app.StructIndexingPanel);
            app.text04.Text = 'mydataMatrix{I1,I2,I3,I4,I5}';
            app.I1Label = uilabel(app.StructIndexingPanel);
            app.I1Label.Text = 'I1 :';
            app.I2Label = uilabel(app.StructIndexingPanel);
            app.I2Label.Text = 'I2 :';
            app.I3Label = uilabel(app.StructIndexingPanel);
            app.I3Label.Text = 'I3 :';
            app.I4Label = uilabel(app.StructIndexingPanel);
            app.I4Label.Text = 'I4 :';
            app.I5Label = uilabel(app.StructIndexingPanel);
            app.I5Label.Text = 'I5 :';
            app.ObservevalueLabel = uilabel(app.StructIndexingPanel);
            app.ObservevalueLabel.Text = 'Observe value :';
            app.DropDown_SelectMetadata = uidropdown(app.StructIndexingPanel);
            app.DropDown_SelectMetadata.Items = {'Chosen Metadata'};
            app.DropDown_SelectMetadata.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetadataValueChanged, true);
            app.DropDown_SelectMetadata.Value = 'Chosen Metadata';
            app.ListBox_SelectMetadata = uilistbox(app.StructIndexingPanel);
            app.ListBox_SelectMetadata.Items = {};
            app.ListBox_SelectMetadata.Value = {};
            app.VariablePanel = uipanel(app.UIFigure);
            app.VariablePanel.Title = 'Variable';
            app.VariablenameEditFieldLabel = uilabel(app.VariablePanel);
            app.VariablenameEditFieldLabel.Text = 'Variable name :';
            app.EditField_Variablename = uieditfield(app.VariablePanel, 'text');
            app.ObjectTypeDropDownLabel = uilabel(app.VariablePanel);
            app.ObjectTypeDropDownLabel.Text = 'Object Type :';
            app.DropDown_ObjectType = uidropdown(app.VariablePanel);
            app.DropDown_ObjectType.Items = {'Selected Object'};
            app.DropDown_ObjectType.ValueChangedFcn = createCallbackFcn(app, @DropDown_ObjectTypeValueChanged, true);
            app.DropDown_ObjectType.Value = 'Selected Object';
            app.MeasurementTypeDropDownLabel = uilabel(app.VariablePanel);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type :';
            app.DropDown_MeasurementType = uidropdown(app.VariablePanel);
            app.DropDown_MeasurementType.Items = {'Selected Measurement'};
            app.DropDown_MeasurementType.ValueChangedFcn = createCallbackFcn(app, @DropDown_MeasurementTypeValueChanged, true);
            app.DropDown_MeasurementType.Value = 'Selected Measurement';
            app.OriginalVariableNameLabel = uilabel(app.VariablePanel);
            app.OriginalVariableNameLabel.Text = 'Original Variable Name';
            app.ListBox_Inputname = uilistbox(app.VariablePanel);
            app.ListBox_Inputname.Items = {};
            app.ListBox_Inputname.ValueChangedFcn = createCallbackFcn(app, @ListBox_InputnameValueChanged, true);
            app.ListBox_Inputname.Value = {};
            app.AssignedNameLabel = uilabel(app.VariablePanel);
            app.AssignedNameLabel.Text = 'Assigned Name';
            app.ListBox_Datasetname = uilistbox(app.VariablePanel);
            app.ListBox_Datasetname.Items = {};
            app.ListBox_Datasetname.ValueChangedFcn = createCallbackFcn(app, @ListBox_DatasetnameValueChanged, true);
            app.ListBox_Datasetname.Value = {};
            app.Button_Add = uibutton(app.VariablePanel, 'push');
            app.Button_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_AddPushed, true);
            app.Button_Add.Icon = 'more.png';
            app.Button_Add.Text = '';
            app.Button_Remove = uibutton(app.VariablePanel, 'push');
            app.Button_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_RemovePushed, true);
            app.Button_Remove.Icon = 'less.png';
            app.Button_Remove.Text = '';
            app.Button_MoveUp = uibutton(app.VariablePanel, 'push');
            app.Button_MoveUp.ButtonPushedFcn = createCallbackFcn(app, @Button_MoveUpPushed, true);
            app.Button_MoveUp.Icon = 'upload.png';
            app.Button_MoveUp.Text = '';
            app.Button_MoveDown = uibutton(app.VariablePanel, 'push');
            app.Button_MoveDown.ButtonPushedFcn = createCallbackFcn(app, @Button_MoveDownPushed, true);
            app.Button_MoveDown.Icon = 'down-arrow.png';
            app.Button_MoveDown.Text = '';
            app.Button_RESET = uibutton(app.UIFigure, 'push');
            app.Button_RESET.ButtonPushedFcn = createCallbackFcn(app, @Button_RESETPushed, true);
            app.Button_RESET.Icon = 'Clear-icon.png';
            app.Button_RESET.Text = 'RESET';
            app.Button_LOAD = uibutton(app.UIFigure, 'push');
            app.Button_LOAD.ButtonPushedFcn = createCallbackFcn(app, @Button_LOADPushed, true);
            app.Button_LOAD.Icon = 'Wait-icon.png';
            app.Button_LOAD.Text = 'LOAD';
            app.Button_SAVE = uibutton(app.UIFigure, 'push');
            app.Button_SAVE.ButtonPushedFcn = createCallbackFcn(app, @Button_SAVEPushed, true);
            app.Button_SAVE.Icon = 'floppy-icon.png';
            app.Button_SAVE.Text = 'SAVE';
            app.Button_RUN = uibutton(app.UIFigure, 'push');
            app.Button_RUN.ButtonPushedFcn = createCallbackFcn(app, @Button_RUNPushed, true);
            app.Button_RUN.Icon = 'rocket.png';
            app.Button_RUN.Text = 'RUN';
            app.Button_STOP = uibutton(app.UIFigure, 'push');
            app.Button_STOP.ButtonPushedFcn = createCallbackFcn(app, @Button_STOPPushed, true);
            app.Button_STOP.Icon = 'alarm.png';
            app.Button_STOP.Text = 'STOP';
            app.Text05 = uilabel(app.UIFigure);
            app.Text05.Text = 'Command Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H5CellProfilerConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end